classdef Laboratorio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label                   matlab.ui.control.Label
        MonitorPanel            matlab.ui.container.Panel
        LampStatus              matlab.ui.control.Lamp
        LabelStatusLamp         matlab.ui.control.Label
        StatusdoEfetuadorPanel  matlab.ui.container.Panel
        StatusJ5                matlab.ui.control.NumericEditField
        J5Label                 matlab.ui.control.Label
        StatusJ4                matlab.ui.control.NumericEditField
        J4Label                 matlab.ui.control.Label
        StatusJ3                matlab.ui.control.NumericEditField
        J3Label                 matlab.ui.control.Label
        StatusJ2                matlab.ui.control.NumericEditField
        J2Label                 matlab.ui.control.Label
        StatusPitch             matlab.ui.control.NumericEditField
        PitchLabel              matlab.ui.control.Label
        StatusPosZ              matlab.ui.control.NumericEditField
        ZcmLabel                matlab.ui.control.Label
        StatusPosY              matlab.ui.control.NumericEditField
        YcmLabel                matlab.ui.control.Label
        StatusJ1                matlab.ui.control.NumericEditField
        J1Label                 matlab.ui.control.Label
        StatusPosX              matlab.ui.control.NumericEditField
        XcmLabel                matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        Panel                   matlab.ui.container.Panel
        VoltaraoMenuButton      matlab.ui.control.StateButton
        BtnConectarCamera       matlab.ui.control.StateButton
        BtnIniciarSimulacao     matlab.ui.control.StateButton
        TabGroup2               matlab.ui.container.TabGroup
        CinemticaDiretaTab      matlab.ui.container.Tab
        Junta5Slider            matlab.ui.control.Slider
        Junta5SliderLabel       matlab.ui.control.Label
        Junta4Slider            matlab.ui.control.Slider
        Junta4SliderLabel       matlab.ui.control.Label
        Junta3Slider            matlab.ui.control.Slider
        Junta3SliderLabel       matlab.ui.control.Label
        Junta2Slider            matlab.ui.control.Slider
        Junta2SliderLabel       matlab.ui.control.Label
        Junta1Slider            matlab.ui.control.Slider
        Junta1SliderLabel       matlab.ui.control.Label
        EditFieldSlider5        matlab.ui.control.NumericEditField
        EditFieldSlider4        matlab.ui.control.NumericEditField
        EditFieldSlider3        matlab.ui.control.NumericEditField
        EditFieldSlider2        matlab.ui.control.NumericEditField
        EditFieldSlider1        matlab.ui.control.NumericEditField
        CinemticaInversaTab     matlab.ui.container.Tab
        PitchCinInv             matlab.ui.control.NumericEditField
        PitchLabel_2            matlab.ui.control.Label
        CheckBoxIA              matlab.ui.control.CheckBox
        BtnCinematicaInversa    matlab.ui.control.StateButton
        PosZCinInv              matlab.ui.control.NumericEditField
        PosioZcmEditFieldLabel  matlab.ui.control.Label
        PosYCinInv              matlab.ui.control.NumericEditField
        PosioYcmEditFieldLabel  matlab.ui.control.Label
        CoordenadaAlvoLabel     matlab.ui.control.Label
        PosXCinInv              matlab.ui.control.NumericEditField
        PosioXcmEditFieldLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        %Property % Description
        
        % Configurar sensor de câmera
        URL_Camera = 'http://192.168.4.3:8080//shot.jpg'; % Substituir pelo IP do celular
        TimerCam  % Vai ser o relógio que atualiza a imagem
        ModeloIA % Carrega modelo IA
        psX      % Guarda a normalização das entradas
        psY      % Guarda a normalização da saída
    end
    
    methods (Access = private)
        function atualizarWebcam(app)
            % --- PROTEÇÃO ---
            % Verificação de Existência
            if ~isvalid(app) || ~isvalid(app.Image)
                return; 
            end
        
            try
                % Se não responder instantaneamente, pula fora para não travar
                opcoes = weboptions('Timeout', 0.5); 
                img = webread(app.URL_Camera, opcoes);
                
                % SUCESSO
                app.LampStatus.Color = 'green';
                app.BtnConectarCamera.Text = 'Conectado';
                app.LabelStatusLamp.Text = 'Online'; % Atualiza texto
                
                % Rotaciona a imagem em 90 graus
                %img = imrotate(img, -90);
                app.Image.ImageSource = img;
                
            catch
                % PARA O TIMER IMEDIATAMENTE!
                % Isso impede que o alerta apareça de novo daqui a 0.5s
                stop(app.TimerCam);
                
                % "Desperte" o botão visualmente
                % Assim o usuário sabe que precisa clicar de novo para religar
                app.BtnConectarCamera.Value = false; 
                
                % 3. Feedback visual de erro
                app.LampStatus.Color = 'red';
                app.LabelStatusLamp.Text = 'Offline';
                
                % Limpa a imagem
                limpartela(app);
                
                % Exibir alerta
                uialert(app.UIFigure, ...
                    'A conexão caiu ou foi recusada. Verifique o celular.', ...
                    'Conexão Perdida', 'Icon', 'error');
                app.BtnConectarCamera.Text = 'Conectar Câmera';
            end
        end
        
        function limpartela(app)
            % Obtém a posição atual do componente Image -> Position = [x, y, largura, altura]
            pos = app.Image.Position;

            % Extrai largura e altura e arredonda (zeros precisa de inteiros)
            largura = round(pos(3));
            altura = round(pos(4));

            % Cria a matriz preta
            % IMPORTANTE: zeros usa (altura, largura) - ordem invertida!
            imgPreta = zeros(altura, largura, 3, 'uint8');

            % Aplica ao componente
            app.Image.ImageSource = imgPreta;
        end

        function cinematicaDireta(app)
            % Parâmetros
            L2 = 18; 
            L3 = 18; 
            L4 = 18; 
            d1 = 10; % offset -> Referente ao Elo 1 (base)

            % Pega os valores atuais dos Sliders
            valT1 = app.Junta1Slider.Value;
            valT2 = app.Junta2Slider.Value;
            valT3 = app.Junta3Slider.Value;
            valT4 = app.Junta4Slider.Value;
            % valT5 = app.Junta5Slider.Value; % Não é usado nas equações X,Y,Z
        
            % Converter entradas para radianos
            t1 = deg2rad(valT1);
            t2 = deg2rad(valT2);
            t3 = deg2rad(valT3);
            t4 = deg2rad(valT4);
        
            % Calcular o Plano Vertical (R e Z) -> VISTA LATERAL
            % Ângulos absolutos em relação ao horizonte
            angle2 = t2;
            angle3 = t2 - t3;
            angle4 = t2 - t3 + t4;
        
            % Raio (Alcance horizontal no plano do braço)
            R = L2 * cos(angle2) + L3 * cos(angle3) + L4 * cos(angle4);
        
            % Altura (Z) - Lembre de somar o offset da base (d1)
            Z = L2 * sin(angle2) + L3 * sin(angle3) + L4 * sin(angle4);
            pz = d1 + Z;
        
            % Projetar no Mundo 3D (X e Y) -> VISTA SUPERIOR
            % Agora usamos o raio (r) e giramos pelo theta1 da base
            px = R * cos(t1);
            py = R * sin(t1);
        
            % Calcular o Pitch Geral (heading)
            % O "heading" é simplesmente a soma dos ângulos de pitch
            pitch = rad2deg(angle4);

            % Saídas no "Status do efetuador"
            app.StatusPosX.Value = px;
            app.StatusPosY.Value = py;
            app.StatusPosZ.Value = pz;
            app.StatusPitch.Value = pitch;
        end

        function [theta1, theta2, theta3, theta4] = cinematicaInversa(app, px, py, pz, pitch)
            % Parâmetros Físicos (cm) - Conforme Tabela de Elos
            L2 = 18; L3 = 18; L4 = 18; d1 = 10;
            
            % Rotação da Base (Plano XY)
            theta1_rad = atan2(py, px);
            
            % Projeção no Plano Radial (RZ)
            r = sqrt(px^2 + py^2);
            z = pz - d1; % Altura a partir do "ombro" (L1)           
            pitch_rad = deg2rad(pitch);
            
            % Localização do Centro do Punho (Wrist Center)
            % Projeta a ponta da garra de volta para a junta 3
            r_punho = r - L4 * cos(pitch_rad); 
            z_punho = z - L4 * sin(pitch_rad); 
            
            % Verificação de Alcance Físico
            D = sqrt(r_punho^2 + z_punho^2);

            if D > (L2 + L3)
                 theta1=NaN; theta2=NaN; theta3=NaN; theta4=NaN; 
                 return;
            end

             % Resolução do Triângulo do Braço (Lei dos Cossenos)
            cos_t3 = (D^2 - L2^2 - L3^2) / (2*L2*L3);
            cos_t3 = max(-1, min(1, cos_t3)); % Proteção contra erros de precisão
        
            % Escolha da Configuração (Cotovelo para Cima/Baixo)
            t3_rad = acos(cos_t3);
            t3_deg = rad2deg(t3_rad);
            
            % Prioriza a solução dentro do limite operacional de 0 a 120 graus
            if (t3_deg >= 0 && t3_deg <= 120)
                theta3_rad = t3_rad;
            else
                % Caso contrário, usa a solução espelhada
                theta3_rad = -t3_rad;
            end
            
            % Cálculo do Ângulo do Ombro (Theta 2)
            % Usando a forma geométrica: atan2(z, r) + atan2(L3*sin(t3), L2 + L3*cos(t3))
            A = L2 + L3 * cos(theta3_rad);
            B = L3 * sin(theta3_rad);
            D_sq = D^2; % Reutiliza D já calculado

            sen_t2 = (z_punho * A + r_punho * B) / D_sq;
            cos_t2 = (r_punho * A - z_punho * B) / D_sq;
            theta2_rad = atan2(sen_t2, cos_t2);

            % Cálculo do Ângulo do Punho (Theta 4)
            % Mantendo a relação: Pitch = Theta2 - Theta3 + Theta4
            % Logo: Theta4 = Pitch - Theta2 + Theta3
            theta4_rad = pitch_rad - theta2_rad + theta3_rad;
        
            % Conversão Final e Limpeza
            theta1 = rad2deg(theta1_rad);
            theta2 = rad2deg(theta2_rad);
            theta3 = rad2deg(theta3_rad);
            theta4 = rad2deg(theta4_rad);

            % Estética: Remove ruídos de sinal próximo a zero
            juntas = [theta1, theta2, theta3, theta4];
            juntas(abs(juntas) < 1e-4) = 0;

            theta1 = juntas(1); 
            theta2 = juntas(2); 
            theta3 = juntas(3); 
            theta4 = juntas(4);
        end

        function enviarParaSimulink(app, j1, j2, j3, j4, j5)
            % Centraliza o envio de dados para o modelo Simulink
            modelo = 'Montagem2';
            
            try
                if bdIsLoaded(modelo)
                    % Aplica as conversões/offsets
                    set_param([modelo '/Slider Gain1'], 'Gain', num2str(j1 * -1)); 
                    set_param([modelo '/Slider Gain2'], 'Gain', num2str(j2 - 90));
                    set_param([modelo '/Slider Gain3'], 'Gain', num2str(j3 + 180));
                    set_param([modelo '/Slider Gain4'], 'Gain', num2str(abs(j4 - 270)));
                    set_param([modelo '/Slider Gain5'], 'Gain', num2str(j5)); 
                end
            catch
                % Se o Simulink não estiver rodando, apenas ignora
            end
        end
    end   


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configura o IP (Ajuste aqui se mudar no celular)
            %app.URL_Camera = 'http://192.168.1.110:8080//shot.jpg'; 

            % Cria o Timer (MAS NÃO INICIA AINDA)
            % Period = 0.1 (Dispara 10 vezes por segundo)
            % Crie um agendamento (timer) que rode num ritmo fixo 
            % (fixedRate), a cada 0.1 segundos (Period), e execute a 
            % função da câmera ignorando os dados padrão do timer (TimerFcn, @(~,~)app...)
            app.TimerCam = timer('ExecutionMode', 'fixedRate', ...
                'Period', 0.1, ... 
                'TimerFcn', @(~,~)app.atualizarWebcam); 
                
            % FORÇA O ESTADO INICIAL DESLIGADO
            app.BtnConectarCamera.Value = false;       
            app.LampStatus.Color = 'red'; 
            app.LabelStatusLamp.Text = 'Aguardando...';

            % Tenta carregar a IA treinada
            try
                dados = load('modelo_mlp_treinado.mat'); % MLP
                %dados = load('modelo_rbf_treinado.mat'); % RBF
                %dados = load('modelo_svr_treinado.mat'); % SVR
                
                % Carregar Modelo
                %app.ModeloIA = dados.Mdl; % SVR
                app.ModeloIA = dados.net_final; % MLP e RBF

                app.psX = dados.psX; % Parâmetros de normalização das entradas
                app.psY = dados.psY; % Parâmetros de normalização da saída
             
            catch
                uialert(app.UIFigure, 'Arquivo de modelo não encontrado!', 'Erro de Inicialização'); 
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Para o timer e limpa a câmera
            if ~isempty(app.TimerCam)
                stop(app.TimerCam);
                delete(app.TimerCam);
            end
            delete(app);
        end

        % Value changed function: BtnConectarCamera
        function BtnConectarCameraValueChanged(app, event)
            estado = app.BtnConectarCamera.Value; 
            if estado == true
                % --- LIGAR ---
                %app.BtnConectarCamera.Text = 'Conectando...';
                app.LampStatus.Color = 'green';
                app.BtnConectarCamera.Text = 'Conectado';
                app.LabelStatusLamp.Text = 'Online';
                                
                start(app.TimerCam); 
            else
                % --- DESLIGAR ---
                stop(app.TimerCam); 

                app.LampStatus.Color = 'red';
                app.BtnConectarCamera.Text = 'Desconectado';
                app.LabelStatusLamp.Text = 'Offline';
                
                % Limpa a imagem
                limpartela(app);
            end
        end

        % Value changed function: BtnIniciarSimulacao
        function BtnIniciarSimulacaoValueChanged(app, event)
            modeloSimulink = 'Montagem2'; 
            estado = app.BtnIniciarSimulacao.Value; 
        
            if estado == true
                % --- LIGANDO A SIMULAÇÃO ---
                app.BtnIniciarSimulacao.Text = 'Conectando...';
                drawnow;
                
                try
                    % Carrega e Inicia o Simulink
                    if ~bdIsLoaded(modeloSimulink)
                        load_system(modeloSimulink);
                    end
                    set_param(modeloSimulink, 'SimulationCommand', 'start');
                    
                    % --- RESET PARA ZERO ---
                    % Isso garante que App e Simulink comecem sincronizados na posição Home
                    
                    % Junta 1
                    app.Junta1Slider.Value = 0;
                    app.EditFieldSlider1.Value = 0;
                    set_param([modeloSimulink '/Slider Gain1'], 'Gain', '0'); % 0 * -1 = 0
                    
                    % Junta 2
                    app.Junta2Slider.Value = 0;
                    app.EditFieldSlider2.Value = 0;
                    set_param([modeloSimulink '/Slider Gain2'], 'Gain', '-90'); % 0 - 90 = -90
                    
                    % Junta 3
                    app.Junta3Slider.Value = 0;
                    app.EditFieldSlider3.Value = 0;
                    set_param([modeloSimulink '/Slider Gain3'], 'Gain', '180'); % 0 + 180 = 180
                    
                    % Junta 4
                    app.Junta4Slider.Value = 0;
                    app.EditFieldSlider4.Value = 0;
                    set_param([modeloSimulink '/Slider Gain4'], 'Gain', '270'); % abs(0-270) = 270
                    
                    % Junta 5
                    app.Junta5Slider.Value = 0;
                    app.EditFieldSlider5.Value = 0;
                    set_param([modeloSimulink '/Slider Gain5'], 'Gain', '0');
                    
                    % --- Atualizar o XYZ na tela ---
                    % Como mudamos os sliders para 0, precisamos recalcular a cinemática
                    %calcularCinematica(app);
                    cinematicaDireta(app);
                    
                    % ---------------------------------------------
                    
                    app.BtnIniciarSimulacao.Text = 'Conectado';
                    
                catch ME
                    app.BtnIniciarSimulacao.Value = false;
                    app.BtnIniciarSimulacao.Text = 'Ligar Gêmeo Digital';
                    uialert(app.UIFigure, ME.message, 'Erro no Simulink');
                end
                
            else
                % --- DESLIGANDO ---
                try
                    set_param(modeloSimulink, 'SimulationCommand', 'stop');
                catch
                end
                app.BtnIniciarSimulacao.Text = 'Ligar Gêmeo Digital';
            end
            
        end

        % Value changing function: Junta1Slider
        function Junta1SliderValueChanging(app, event)
            % Pega o valor no slider referente a junta 1
            j1ValReal = event.Value; 

            % Atualiza a interface
            app.EditFieldSlider1.Value = j1ValReal; 
            app.StatusJ1.Value = j1ValReal;
            
            % Lógica do SIMULINK
            % 0 (Real) -> -110 (Simulink)
            % Simu: 0 virou 0; 120 virou -120
            j1ValSimulink = j1ValReal * -1; 
            
            try
                set_param(['Montagem2' '/Slider Gain1'], 'Gain', num2str(j1ValSimulink));
            catch
            end
            
            % Atualiza o cálculo matemático em tempo real
            % Precisa forçar o valor no slider antes de calcular, pois no 'Changing' ele ainda não fixou
            app.Junta1Slider.Value = j1ValReal; 
            %calcularCinematica(app);
            cinematicaDireta(app);
        end

        % Value changing function: Junta2Slider
        function Junta2SliderValueChanging(app, event)
            % Pega o valor no slider referente a junta 2
            j2ValReal = event.Value;
          
            % Atualiza a interface
            app.EditFieldSlider2.Value = j2ValReal; 
            app.StatusJ2.Value = j2ValReal;
            
            % Lógica do SIMULINK (Mantenha sua lógica invertida aqui se é o que o robô pede)
            % Simu: 0 virou -90; 120 virou 30
            j2ValSimulink = j2ValReal - 90;
            
            try
                set_param(['Montagem2' '/Slider Gain2'], 'Gain', num2str(j2ValSimulink));
            catch
            end
            
            % Atualiza o cálculo matemático em tempo real
            % Precisa forçar o valor no slider antes de calcular, pois no 'Changing' ele ainda não fixou
            app.Junta2Slider.Value = j2ValReal; 
            %calcularCinematica(app);
            cinematicaDireta(app);
        end

        % Value changing function: Junta3Slider
        function Junta3SliderValueChanging(app, event)
            % Pega o valor no slider referente a junta 3
            j3ValReal = event.Value;

            % Atualiza a interface
            app.EditFieldSlider3.Value = j3ValReal; 
            app.StatusJ3.Value = j3ValReal;
            
            % Lógica do SIMULINK (Mantenha sua lógica invertida aqui se é o que o robô pede)
            % Simu: 0 virou 180; 120 virou 300
            j3ValSimulink = j3ValReal + 180;
            
            try
                set_param(['Montagem2' '/Slider Gain3'], 'Gain', num2str(j3ValSimulink));
            catch
            end
            
            % Atualiza o cálculo matemático em tempo real
            % Precisa forçar o valor no slider antes de calcular, pois no 'Changing' ele ainda não fixou
            app.Junta3Slider.Value = j3ValReal; 
            %calcularCinematica(app);
            cinematicaDireta(app);
        end

        % Value changing function: Junta4Slider
        function Junta4SliderValueChanging(app, event)
            % Pega o valor no slider referente a junta 4
            j4ValReal = event.Value;

            % Atualiza a interface
            app.EditFieldSlider4.Value = j4ValReal; 
            app.StatusJ4.Value = j4ValReal;
            
            % Lógica do SIMULINK (Mantenha sua lógica invertida aqui se é o que o robô pede)
            % Simu: 0 virou 270; 120 virou 150 (Decrescente)
            j4ValSimulink = abs(j4ValReal - 270); 
            
            try
                set_param(['Montagem2' '/Slider Gain4'], 'Gain', num2str(j4ValSimulink));
            catch
            end
            
            % Atualiza o cálculo matemático em tempo real
            % Precisa forçar o valor no slider antes de calcular, pois no 'Changing' ele ainda não fixou
            app.Junta4Slider.Value = j4ValReal; 
            %calcularCinematica(app);
            cinematicaDireta(app);
        end

        % Value changing function: Junta5Slider
        function Junta5SliderValueChanging(app, event)
            % Pega o valor no slider referente a junta 5
            j5ValReal = event.Value; % Valor da Interface (0 a 120)
            
            % Atualiza a interface
            app.EditFieldSlider5.Value = j5ValReal; 
            app.StatusJ5.Value = j5ValReal;
            
            % --- NÃO PRECISA DE TRADUÇÃO ---
            % Envia para o Modelo no Simulink
            try
                set_param(['Montagem2' '/Slider Gain5'], 'Gain', num2str(j5ValReal));
            catch
            end
        end

        % Value changed function: BtnCinematicaInversa
        function BtnCinematicaInversaValueChanged(app, event)
            % Pegar os valores Px, Py e Pz
            px = app.PosXCinInv.Value; 
            py = app.PosYCinInv.Value;
            pz = app.PosZCinInv.Value;
         
            % ---------------------------------------------------------------
            % VERIFICAÇÃO DE ALCANCE REAL (esfera de trabalho)
            % Calculamos r, z e dtotal aqui uma única vez.
            % Esses valores são reutilizados abaixo no modo IA.
            % ---------------------------------------------------------------
            L2 = 18; 
            L3 = 18; 
            L4 = 18; 
            d1 = 10;

            r      = sqrt(px^2 + py^2);
            z      = pz - d1;
            dtotal = sqrt(r^2 + z^2);
            alcance_max = L2 + L3 + L4; % 54 cm
         
            if dtotal > alcance_max + 0.5  % +0.5 cm de tolerância para pontos no limite exato
                uialert(app.UIFigure, ...
                    sprintf(['A posição (%.1f, %.1f, %.1f) cm está fora do alcance físico do braço.\n\n' ...
                             'Distância ao ombro: %.1f cm (máximo: %.0f cm).\n' ...
                             'Tente uma posição mais próxima da base.'], ...
                             px, py, pz, dtotal, alcance_max), ...
                    'Posição Inalcançável', 'Icon', 'error');
                return;
            end
         
            % ---------------------------------------------------------------
            % DETERMINAÇÃO DO PITCH (IA ou Manual)
            % ---------------------------------------------------------------
            usar_ia = app.CheckBoxIA.Value;
         
            if usar_ia
                % --- MODO IA ATIVADO ---
                rxy = sqrt(px^2 + py^2); % mesmo que r, mas nome explícito para a IA
                phi = rad2deg(atan2(py, px));
         
                if dtotal > 53.0
                    % Próximo do limite máximo do workspace:
                    % A IA tende a errar o pitch nesssa região pois há poucos
                    % pontos de treino no extremo. Calculamos geometricamente
                    % o pitch ideal, que é o ângulo do braço totalmente esticado.
                    % Exemplos:
                    %   (54, 0, 10) -> atan2(0,  54) =  0°  (horizontal)
                    %   (0,  0, 64) -> atan2(54,  0) = 90°  (vertical)
                    %   (38, 0, 48) -> atan2(38, 38) = 45°  (diagonal)
                    pitch_final = rad2deg(atan2(z, r));
                    cor_pitch_temp = [0 0.5 0]; % Verde = modo segurança
                    %app.PitchCinInv.FontColor = [0 0.5 0]; % Verde = modo segurança
                else
                    % Região normal: usa a IA
                    % ENTRADA
                    % =========================================================
                    % REDES NEURAIS (MLP ou RBFN)
                    % =========================================================
                    % Descomente este bloco se estiver usando 'net' (MLP/RBFN)
                    entrada_raw = [px; py; pz; rxy; dtotal; phi];
                    entrada_norm = mapminmax('apply', entrada_raw, app.psX);
                    % ---------------------------------------------------------

                    % =========================================================
                    % MACHINE LEARNING (SVR)
                    % =========================================================
                    % Descomente este bloco se estiver usando 'Mdl' (SVR)
                    %entrada_raw = [px, py, pz, rxy, dtotal, phi];
                    %entrada_norm = mapminmax('apply', entrada_raw, app.psX);
                    % ---------------------------------------------------------

                    % SAÍDA
                    %pitch_norm = predict(app.ModeloIA, entrada_norm); % SVR
                    pitch_norm = app.ModeloIA(entrada_norm); % MLP ou RBF
                    pitch_final = mapminmax('reverse', pitch_norm, app.psY);

                    % Recurso didático
                    cor_pitch_temp = [0 0 1]; % Azul = IA
                    %app.PitchCinInv.FontColor = [0 0 1]; % Azul = IA
                end
         
                %app.PitchCinInv.Value = pitch_final;
                %app.StatusPitch.Value = pitch_final;
         
            else
                % --- MODO MANUAL ---
                pitch_final = app.PitchCinInv.Value;
                %app.StatusPitch.Value = pitch_final;
                
                % Recurso didático
                cor_pitch_temp = [1 0 0]; % Vermelho = manual
                %app.PitchCinInv.FontColor = [1 0 0]; % Vermelho = manual
            end
         
            % ---------------------------------------------------------------
            % CÁLCULO DA CINEMÁTICA INVERSA
            % ---------------------------------------------------------------
            [t1, t2, t3, t4] = cinematicaInversa(app, px, py, pz, pitch_final);
         
            % ---------------------------------------------------------------
            % VERIFICAÇÃO 1 — Impossibilidade geométrica
            % Ocorre quando o wrist center está além de L2+L3
            % cinematicaInversa retorna NaN nesse caso
            % ---------------------------------------------------------------
            if isnan(t1)
                uialert(app.UIFigure, ...
                    sprintf(['A orientação solicitada é geometricamente impossível para esta posição.\n\n' ...
                             'Tente ajustar o pitch manualmente ou escolha uma posição diferente.']), ...
                    'Configuração Inválida', 'Icon', 'error');
                return;
            end
         
            % ---------------------------------------------------------------
            % VERIFICAÇÃO 2 — Limites físicos dos servos (0° a 120°)
            % ---------------------------------------------------------------
            juntas_vals  = [t1,   t2,   t3,   t4  ];
            juntas_nomes = ["J1", "J2", "J3", "J4"];
            fora = find(juntas_vals < 0 | juntas_vals > 120);
         
            if ~isempty(fora)
                detalhes = "";
                for i = fora
                    detalhes = detalhes + sprintf("  • %s: %.1f°  (limite: 0° – 120°)\n", ...
                                                   juntas_nomes(i), juntas_vals(i));
                end
                uialert(app.UIFigure, ...
                    sprintf(['A configuração calculada excede os limites físicos dos servos:\n\n' ...
                             '%s\n' ...
                             'Sugestão: ajuste o pitch manualmente ou escolha uma posição diferente.'], ...
                             detalhes), ...
                    'Configuração Inatingível', 'Icon', 'warning');
                return;
            end
         
            % ---------------------------------------------------------------
            % SUCESSO — Atualiza interface e envia para o Simulink
            % ---------------------------------------------------------------
            % Atualiza a UI do Pitch (agora sim!)
            app.PitchCinInv.Value = pitch_final;
            app.PitchCinInv.FontColor = cor_pitch_temp;
            app.StatusPitch.Value = pitch_final;

            % Pega valor do slider
            t5 = app.Junta5Slider.Value;
         
            % Atualiza sliders
            app.Junta1Slider.Value = t1;
            app.Junta2Slider.Value = t2;
            app.Junta3Slider.Value = t3;
            app.Junta4Slider.Value = t4;
         
            % Atualiza edit fields dos sliders
            app.EditFieldSlider1.Value = t1;
            app.EditFieldSlider2.Value = t2;
            app.EditFieldSlider3.Value = t3;
            app.EditFieldSlider4.Value = t4;
            app.EditFieldSlider5.Value = t5;
         
            % Atualiza status das juntas
            app.StatusJ1.Value = t1;
            app.StatusJ2.Value = t2;
            app.StatusJ3.Value = t3;
            app.StatusJ4.Value = t4;
            app.StatusJ5.Value = t5;
         
            % Atualiza posição cartesiana no status
            app.StatusPosX.Value = px;
            app.StatusPosY.Value = py;
            app.StatusPosZ.Value = pz;
         
            % Envia para o Gêmeo Digital
            enviarParaSimulink(app, t1, t2, t3, t4, t5);
        end

        % Value changed function: CheckBoxIA
        function CheckBoxIAValueChanged(app, event)
        if app.CheckBoxIA.Value == true
            app.PitchCinInv.Enable = 'off'; % Trava o campo
            app.PitchCinInv.Value = 0;      % Ou limpa visualmente
        else
            app.PitchCinInv.Enable = 'on';  % Destrava
        end            
        end

        % Value changed function: EditFieldSlider1
        function EditFieldSlider1ValueChanged(app, event)
            % Pega o valor digitado pelo usuário
            valJ1 = app.EditFieldSlider1.Value;

            % Verifica se o valor está dentro do limite da junta
            if valJ1 < 0 || valJ1 > 120
                msg = "O ângulo da Junta 1 está fora de alcance! Por favor digite um valor entre 0° e 120°.";
                uialert(app.UIFigure, msg, 'Valor inválido!', 'Icon', 'warning');

                % Desfaz a digitação errada na tela, voltando para o valor real do Slider
                app.EditFieldSlider1.Value = app.Junta1Slider.Value;
            else
                % Atualiza o respectivo slider gain
                app.Junta1Slider.Value = valJ1;
    
                % Atualiza o status da interface
                app.StatusJ1.Value = valJ1;
    
                % Envia o valor para o modelo no Simulink
                % Simu: 0 virou 0; 120 virou -120
                valJ1Sim = valJ1 * -1; 
                
                try
                    set_param(['Montagem2' '/Slider Gain1'], 'Gain', num2str(valJ1Sim));
                catch
                end
                
                % Realizar a Cinemática Direta
                cinematicaDireta(app);
            end
        end

        % Value changed function: EditFieldSlider2
        function EditFieldSlider2ValueChanged(app, event)
            % Pega o valor digitado pelo usuário
            valJ2 = app.EditFieldSlider2.Value;

            % Verifica se o valor está dentro do limite da junta
            if valJ2 < 0 || valJ2 > 120
                msg = "O ângulo da Junta 2 está fora de alcance! Por favor digite um valor entre 0° e 120°.";
                uialert(app.UIFigure, msg, 'Valor inválido!', 'Icon', 'warning');

                % Desfaz a digitação errada na tela, voltando para o valor real do Slider
                app.EditFieldSlider2.Value = app.Junta2Slider.Value;
            else
                % Atualiza o respectivo slider gain
                app.Junta2Slider.Value = valJ2;
    
                % Atualiza o status da interface
                app.StatusJ2.Value = valJ2;
    
                % Envia o valor para o modelo no Simulink
                % Simu: 0 virou -90; 120 virou 30
                valJ2Sim = valJ2 - 90;
                
                try
                    set_param(['Montagem2' '/Slider Gain2'], 'Gain', num2str(valJ2Sim));
                catch
                end
                
                % Realizar a Cinemática Direta
                cinematicaDireta(app);
            end
        end

        % Value changed function: EditFieldSlider3
        function EditFieldSlider3ValueChanged(app, event)
            % Pega o valor digitado pelo usuário
            valJ3 = app.EditFieldSlider3.Value;

            % Verifica se o valor está dentro do limite da junta
            if valJ3 < 0 || valJ3 > 120
                msg = "O ângulo da Junta 3 está fora de alcance! Por favor digite um valor entre 0° e 120°.";
                uialert(app.UIFigure, msg, 'Valor inválido!', 'Icon', 'warning');

                % Desfaz a digitação errada na tela, voltando para o valor real do Slider
                app.EditFieldSlider3.Value = app.Junta3Slider.Value;
            else
                % Atualiza o respectivo slider gain
                app.Junta3Slider.Value = valJ3;
    
                % Atualiza o status da interface
                app.StatusJ3.Value = valJ3;
    
                % Envia o valor para o modelo no Simulink
                % Simu: 0 virou 180; 120 virou 300
                valJ3Sim = valJ3 + 180;
                
                try
                    set_param(['Montagem2' '/Slider Gain3'], 'Gain', num2str(valJ3Sim));
                catch
                end
                
                % Realizar a Cinemática Direta
                cinematicaDireta(app);
            end
        end

        % Value changed function: EditFieldSlider4
        function EditFieldSlider4ValueChanged(app, event)
            % Pega o valor digitado pelo usuário
            valJ4 = app.EditFieldSlider4.Value;

            % Verifica se o valor está dentro do limite da junta
            if valJ4 < 0 || valJ4 > 120
                msg = "O ângulo da Junta 4 está fora de alcance! Por favor digite um valor entre 0° e 120°.";
                uialert(app.UIFigure, msg, 'Valor inválido!', 'Icon', 'warning');

                % Desfaz a digitação errada na tela, voltando para o valor real do Slider
                app.EditFieldSlider4.Value = app.Junta4Slider.Value;
            else
                % Atualiza o respectivo slider gain
                app.Junta4Slider.Value = valJ4;
    
                % Atualiza o status da interface
                app.StatusJ4.Value = valJ4;
    
                % Envia o valor para o modelo no Simulink
                % Simu: 0 virou 270; 120 virou 150 (Decrescente)
                valJ4Sim = abs(valJ4 - 270); 
                
                try
                    set_param(['Montagem2' '/Slider Gain4'], 'Gain', num2str(valJ4Sim));
                catch
                end
    
                % Realizar a Cinemática Direta
                cinematicaDireta(app);
            end
        end

        % Value changed function: EditFieldSlider5
        function EditFieldSlider5ValueChanged(app, event)
           % Pega o valor digitado pelo usuário
            valJ5 = app.EditFieldSlider5.Value;

            % Verifica se o valor está dentro do limite da junta
            if valJ5 < 0 || valJ5 > 120
                msg = "O ângulo da Junta 5 está fora de alcance! Por favor digite um valor entre 0° e 120°.";
                uialert(app.UIFigure, msg, 'Valor inválido!', 'Icon', 'warning');

                % Desfaz a digitação errada na tela, voltando para o valor real do Slider
                app.EditFieldSlider5.Value = app.Junta5Slider.Value;
            else
                % Atualiza o respectivo slider gain
                app.Junta5Slider.Value = valJ5;
    
                % Atualiza o status da interface
                app.StatusJ5.Value = valJ5;
    
                % Envia o valor para o modelo no Simulink
                % Não precisa de consideração
                try
                    set_param(['Montagem2' '/Slider Gain5'], 'Gain', num2str(valJ5));
                catch
                end
    
                % Realizar a Cinemática Direta
                cinematicaDireta(app);
            end
        end

        % Value changed function: VoltaraoMenuButton
        function VoltaraoMenuButtonValueChanged(app, event)
            modeloSimulink = 'Montagem2'; 
            
            % Para o Simulink se estiver rodando (Segurança)
            try
                % Verifica se o modelo está carregado antes de tentar parar
                if bdIsLoaded(modeloSimulink)
                    set_param(modeloSimulink, 'SimulationCommand', 'stop');
                end
            catch
                % Se der erro ou já estiver fechado, apenas segue a vida
            end
            % Abre o Menu Principal
            MenuPrincipal;

            exportapp(app.UIFigure, 'imagem.png')
            
            % Fecha a janela atual
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.TabGroup2 = uitabgroup(app.Panel);
            app.CinemticaDiretaTab = uitab(app.TabGroup2);
            app.CinemticaDiretaTab.Title = 'Cinemática Direta';
            app.EditFieldSlider1 = uieditfield(app.CinemticaDiretaTab, 'numeric');
            app.EditFieldSlider1.ValueDisplayFormat = '%.1f';
            app.EditFieldSlider1.ValueChangedFcn = createCallbackFcn(app, @EditFieldSlider1ValueChanged, true);
            app.EditFieldSlider2 = uieditfield(app.CinemticaDiretaTab, 'numeric');
            app.EditFieldSlider2.ValueDisplayFormat = '%.1f';
            app.EditFieldSlider2.ValueChangedFcn = createCallbackFcn(app, @EditFieldSlider2ValueChanged, true);
            app.EditFieldSlider3 = uieditfield(app.CinemticaDiretaTab, 'numeric');
            app.EditFieldSlider3.ValueDisplayFormat = '%.1f';
            app.EditFieldSlider3.ValueChangedFcn = createCallbackFcn(app, @EditFieldSlider3ValueChanged, true);
            app.EditFieldSlider4 = uieditfield(app.CinemticaDiretaTab, 'numeric');
            app.EditFieldSlider4.ValueDisplayFormat = '%.1f';
            app.EditFieldSlider4.ValueChangedFcn = createCallbackFcn(app, @EditFieldSlider4ValueChanged, true);
            app.EditFieldSlider5 = uieditfield(app.CinemticaDiretaTab, 'numeric');
            app.EditFieldSlider5.ValueDisplayFormat = '%.1f';
            app.EditFieldSlider5.ValueChangedFcn = createCallbackFcn(app, @EditFieldSlider5ValueChanged, true);
            app.Junta1SliderLabel = uilabel(app.CinemticaDiretaTab);
            app.Junta1SliderLabel.Text = 'Junta 1';
            app.Junta1Slider = uislider(app.CinemticaDiretaTab);
            app.Junta1Slider.Limits = [0 120];
            app.Junta1Slider.ValueChangingFcn = createCallbackFcn(app, @Junta1SliderValueChanging, true);
            app.Junta2SliderLabel = uilabel(app.CinemticaDiretaTab);
            app.Junta2SliderLabel.Text = 'Junta 2';
            app.Junta2Slider = uislider(app.CinemticaDiretaTab);
            app.Junta2Slider.Limits = [0 120];
            app.Junta2Slider.ValueChangingFcn = createCallbackFcn(app, @Junta2SliderValueChanging, true);
            app.Junta3SliderLabel = uilabel(app.CinemticaDiretaTab);
            app.Junta3SliderLabel.Text = 'Junta 3';
            app.Junta3Slider = uislider(app.CinemticaDiretaTab);
            app.Junta3Slider.Limits = [0 120];
            app.Junta3Slider.ValueChangingFcn = createCallbackFcn(app, @Junta3SliderValueChanging, true);
            app.Junta4SliderLabel = uilabel(app.CinemticaDiretaTab);
            app.Junta4SliderLabel.Text = 'Junta 4';
            app.Junta4Slider = uislider(app.CinemticaDiretaTab);
            app.Junta4Slider.Limits = [0 120];
            app.Junta4Slider.ValueChangingFcn = createCallbackFcn(app, @Junta4SliderValueChanging, true);
            app.Junta5SliderLabel = uilabel(app.CinemticaDiretaTab);
            app.Junta5SliderLabel.Text = 'Junta 5';
            app.Junta5Slider = uislider(app.CinemticaDiretaTab);
            app.Junta5Slider.Limits = [0 120];
            app.Junta5Slider.ValueChangingFcn = createCallbackFcn(app, @Junta5SliderValueChanging, true);
            app.CinemticaInversaTab = uitab(app.TabGroup2);
            app.CinemticaInversaTab.Title = 'Cinemática Inversa';
            app.PosioXcmEditFieldLabel = uilabel(app.CinemticaInversaTab);
            app.PosioXcmEditFieldLabel.Text = 'Posição X (cm)';
            app.PosXCinInv = uieditfield(app.CinemticaInversaTab, 'numeric');
            app.CoordenadaAlvoLabel = uilabel(app.CinemticaInversaTab);
            app.CoordenadaAlvoLabel.Text = 'Coordenada Alvo:';
            app.PosioYcmEditFieldLabel = uilabel(app.CinemticaInversaTab);
            app.PosioYcmEditFieldLabel.Text = 'Posição Y (cm)';
            app.PosYCinInv = uieditfield(app.CinemticaInversaTab, 'numeric');
            app.PosioZcmEditFieldLabel = uilabel(app.CinemticaInversaTab);
            app.PosioZcmEditFieldLabel.Text = 'Posição Z (cm)';
            app.PosZCinInv = uieditfield(app.CinemticaInversaTab, 'numeric');
            app.BtnCinematicaInversa = uibutton(app.CinemticaInversaTab, 'state');
            app.BtnCinematicaInversa.ValueChangedFcn = createCallbackFcn(app, @BtnCinematicaInversaValueChanged, true);
            app.BtnCinematicaInversa.Text = 'Calcular e Mover';
            app.CheckBoxIA = uicheckbox(app.CinemticaInversaTab);
            app.CheckBoxIA.ValueChangedFcn = createCallbackFcn(app, @CheckBoxIAValueChanged, true);
            app.CheckBoxIA.Text = 'Auto (IA)';
            app.PitchLabel_2 = uilabel(app.CinemticaInversaTab);
            app.PitchLabel_2.Text = 'Pitch (°)';
            app.PitchCinInv = uieditfield(app.CinemticaInversaTab, 'numeric');
            app.BtnIniciarSimulacao = uibutton(app.Panel, 'state');
            app.BtnIniciarSimulacao.ValueChangedFcn = createCallbackFcn(app, @BtnIniciarSimulacaoValueChanged, true);
            app.BtnIniciarSimulacao.Text = 'Ligar Modelo Digital';
            app.BtnConectarCamera = uibutton(app.Panel, 'state');
            app.BtnConectarCamera.ValueChangedFcn = createCallbackFcn(app, @BtnConectarCameraValueChanged, true);
            app.BtnConectarCamera.Text = 'Conectar Câmera';
            app.VoltaraoMenuButton = uibutton(app.Panel, 'state');
            app.VoltaraoMenuButton.ValueChangedFcn = createCallbackFcn(app, @VoltaraoMenuButtonValueChanged, true);
            app.VoltaraoMenuButton.Text = 'Voltar ao Menu';
            app.MonitorPanel = uipanel(app.UIFigure);
            app.MonitorPanel.Title = 'Monitor';
            app.Image = uiimage(app.MonitorPanel);
            app.StatusdoEfetuadorPanel = uipanel(app.MonitorPanel);
            app.StatusdoEfetuadorPanel.Title = 'Status do Efetuador';
            app.XcmLabel = uilabel(app.StatusdoEfetuadorPanel);
            app.XcmLabel.Text = 'X(cm)';
            app.StatusPosX = uieditfield(app.StatusdoEfetuadorPanel, 'numeric');
            app.StatusPosX.ValueDisplayFormat = '%.1f';
            app.StatusPosX.Editable = 'off';
            app.J1Label = uilabel(app.StatusdoEfetuadorPanel);
            app.J1Label.Text = 'J1(°)';
            app.StatusJ1 = uieditfield(app.StatusdoEfetuadorPanel, 'numeric');
            app.StatusJ1.ValueDisplayFormat = '%.1f';
            app.StatusJ1.Editable = 'off';
            app.YcmLabel = uilabel(app.StatusdoEfetuadorPanel);
            app.YcmLabel.Text = 'Y(cm)';
            app.StatusPosY = uieditfield(app.StatusdoEfetuadorPanel, 'numeric');
            app.StatusPosY.ValueDisplayFormat = '%.1f';
            app.StatusPosY.Editable = 'off';
            app.ZcmLabel = uilabel(app.StatusdoEfetuadorPanel);
            app.ZcmLabel.Text = 'Z(cm)';
            app.StatusPosZ = uieditfield(app.StatusdoEfetuadorPanel, 'numeric');
            app.StatusPosZ.ValueDisplayFormat = '%.1f';
            app.StatusPosZ.Editable = 'off';
            app.PitchLabel = uilabel(app.StatusdoEfetuadorPanel);
            app.PitchLabel.Text = 'Pitch(°)';
            app.StatusPitch = uieditfield(app.StatusdoEfetuadorPanel, 'numeric');
            app.StatusPitch.ValueDisplayFormat = '%.1f';
            app.StatusPitch.Editable = 'off';
            app.J2Label = uilabel(app.StatusdoEfetuadorPanel);
            app.J2Label.Text = 'J2(°)';
            app.StatusJ2 = uieditfield(app.StatusdoEfetuadorPanel, 'numeric');
            app.StatusJ2.ValueDisplayFormat = '%.1f';
            app.StatusJ2.Editable = 'off';
            app.J3Label = uilabel(app.StatusdoEfetuadorPanel);
            app.J3Label.Text = 'J3(°)';
            app.StatusJ3 = uieditfield(app.StatusdoEfetuadorPanel, 'numeric');
            app.StatusJ3.ValueDisplayFormat = '%.1f';
            app.StatusJ3.Editable = 'off';
            app.J4Label = uilabel(app.StatusdoEfetuadorPanel);
            app.J4Label.Text = 'J4(°)';
            app.StatusJ4 = uieditfield(app.StatusdoEfetuadorPanel, 'numeric');
            app.StatusJ4.ValueDisplayFormat = '%.1f';
            app.StatusJ4.Editable = 'off';
            app.J5Label = uilabel(app.StatusdoEfetuadorPanel);
            app.J5Label.Text = 'J5(°)';
            app.StatusJ5 = uieditfield(app.StatusdoEfetuadorPanel, 'numeric');
            app.StatusJ5.ValueDisplayFormat = '%.1f';
            app.StatusJ5.Editable = 'off';
            app.LabelStatusLamp = uilabel(app.MonitorPanel);
            app.LabelStatusLamp.Text = 'Desconectado';
            app.LampStatus = uilamp(app.MonitorPanel);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-------------------------------------------------------------------------------';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Laboratorio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end