classdef SalaDeAula < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        VoltaraoMenuButton             matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        VisoGeralTab                   matlab.ui.container.Tab
        Panel_2                        matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        Panel                          matlab.ui.container.Panel
        UnidadedePotnciaLabel_2        matlab.ui.control.Label
        UnidadedeControleLabel_2       matlab.ui.control.Label
        ConversorBuckePCILabel         matlab.ui.control.Label
        ESP32Label_2                   matlab.ui.control.Label
        CmeradeSmartphoneLabel         matlab.ui.control.Label
        SensorLabel                    matlab.ui.control.Label
        ServoMotorMG9952MicroServoMG90SLabel  matlab.ui.control.Label
        AtuadoresLabel                 matlab.ui.control.Label
        RotacionaisLabel               matlab.ui.control.Label
        GrausdeLiberdade5RotacionaisLabel_2  matlab.ui.control.Label
        GrausdeLiberdadeLabel          matlab.ui.control.Label
        EspecificaesLabel              matlab.ui.control.Label
        ManipuladorDidticode5JuntasRotativasLabel  matlab.ui.control.Label
        DecomposioGeomtricaTab         matlab.ui.container.Tab
        Panel_7                        matlab.ui.container.Panel
        Button                         matlab.ui.control.Button
        GrausdeLiberdade5RotacionaisLabel_5  matlab.ui.control.Label
        VistaLateralPlanoRZLabel       matlab.ui.control.Label
        AxLateral                      matlab.ui.control.UIAxes
        DesacoplamentoCinemticoMtodoGeomtricoLabel_2  matlab.ui.control.Label
        DesacoplamentoCinemticoMtodoGeomtricoLabel  matlab.ui.control.Label
        Panel_6                        matlab.ui.container.Panel
        GrausdeLiberdade5RotacionaisLabel_4  matlab.ui.control.Label
        VistaSuperiorPlanoXYLabel      matlab.ui.control.Label
        AxSuperior                     matlab.ui.control.UIAxes
        ModelagemMatemticaTab          matlab.ui.container.Tab
        Image2_2                       matlab.ui.control.Image
        Tabela2VariveisdeJuntaLabel    matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        Tabela1ElosdoManipuladorLabel  matlab.ui.control.Label
        Panel_8                        matlab.ui.container.Panel
        LabelEqDireta                  matlab.ui.control.Label
        CinematicaDropDown             matlab.ui.control.DropDown
        CinemticaDropDownLabel         matlab.ui.control.Label
        LabelEqInversa                 matlab.ui.control.Label
    end

    methods (Access = private)
        function atualizarDiagramasGeometricos(app)
            % --- PARÂMETROS FÍSICOS (Simulação) ---
            L2 = 18; L3 = 18; L4 = 18; 
            
            % --- CONFIGURAÇÃO DA VISTA SUPERIOR (Plano XY) ---
            th1_exemplo = deg2rad(35); % Ângulo da base
            r_exemplo = 20;            % Alcance horizontal
            
            Px = r_exemplo * cos(th1_exemplo);
            Py = r_exemplo * sin(th1_exemplo);
            
            % Limpa e Prepara
            cla(app.AxSuperior);
            hold(app.AxSuperior, 'on');
            
            % 1. Desenha Vetores
            plot(app.AxSuperior, [0 Px], [0 Py], 'k-', 'LineWidth', 2.5); % Vetor Principal
            plot(app.AxSuperior, [Px Px], [0 Py], 'k:', 'LineWidth', 1);  % Projeção X
            plot(app.AxSuperior, [0 Px], [Py Py], 'k:', 'LineWidth', 1);  % Projeção Y
            
            % 2. Arco do Ângulo Theta 1
            t = linspace(0, th1_exemplo, 30);
            raio_arco = r_exemplo * 0.4;
            plot(app.AxSuperior, raio_arco*cos(t), raio_arco*sin(t), 'r-', 'LineWidth', 2);
            
            % 3. Textos e Etiquetas (Ajustados para não sobrepor)
            text(app.AxSuperior, Px/2, (Py/2) + 4, 'R', 'Color', 'k', 'FontWeight', 'bold');
            text(app.AxSuperior, Px, -1.5, 'P_x', 'HorizontalAlignment', 'center'); % Px embaixo
            text(app.AxSuperior, -1.5, Py, 'P_y', 'HorizontalAlignment', 'right');  % Py na esquerda
            text(app.AxSuperior, raio_arco+1, 1.5, '\theta_1', 'Color', 'r', 'FontSize', 12, 'FontWeight', 'bold');
            
            % 4. Ajustes de Eixo (Para nada ficar cortado)
            axis(app.AxSuperior, 'equal');
            grid(app.AxSuperior, 'on');
            xlabel(app.AxSuperior, 'Eixo X (cm)');
            ylabel(app.AxSuperior, 'Eixo Y (cm)');
            app.AxSuperior.XLim = [-5, 25]; % Margem extra
            app.AxSuperior.YLim = [-5, 20];
            
            
            % --- CONFIGURAÇÃO DA VISTA LATERAL (Plano RZ) ---
            th2 = deg2rad(40);  % Inclinação inicial
            th3 = deg2rad(0);   % Horizontal
            th4 = deg2rad(40);  % Inclinação final
            
            % Pontos da Cinemática
            P0 = [0, 0];
            P1 = [L2*cos(th2), L2*sin(th2)];
            P2 = [P1(1) + L3*cos(th3), P1(2) + L3*sin(th3)];
            P3 = [P2(1) + L4*cos(th4), P2(2) + L4*sin(th4)];

            % Limpa e Prepara
            cla(app.AxLateral);
            hold(app.AxLateral, 'on');
            
            % 1. Desenha o Robô (Esqueleto)
            plot(app.AxLateral, [P0(1) P1(1)], [P0(2) P1(2)], 'k-', 'LineWidth', 2.5); % Elo L2 (Grossa)
            plot(app.AxLateral, [P1(1) P2(1)], [P1(2) P2(2)], 'b-o', 'LineWidth', 2.5, 'MarkerSize', 3, 'MarkerFaceColor','w'); % L3
            plot(app.AxLateral, [P2(1) P3(1)], [P2(2) P3(2)], 'r-o', 'LineWidth', 2.5, 'MarkerSize', 3, 'MarkerFaceColor','w'); % L4
            
            % 2. Vetor de Fechamento (Lei dos Cossenos)
            %plot(app.AxLateral, [P1(1) P3(1)], [P1(2) P3(2)], '--', 'Color', [0.5 0.5 0.5], 'LineWidth', 1.5); % Linha tracejada vermelha
            
            % 3. Textos
            % Ajuste fino: L2 agora aparece na esquerda sem ser cortado
            text(app.AxLateral, P1(1)/2 - 2, P1(2)/2 + 2, 'L_2', 'HorizontalAlignment', 'right', 'FontWeight', 'bold');
            text(app.AxLateral, (P1(1)+P2(1))/2, (P1(2)+P2(2))/2 + 4, 'L_3', 'Color', 'b', 'FontWeight', 'bold');
            text(app.AxLateral, (P2(1)+P3(1))/2 - 5.5, (P2(2)+P3(2))/2 + 2, 'L_4 + L_5', 'Color', 'r', 'FontWeight', 'bold');
            %text(app.AxLateral, (P1(1) + P3(1)) / 2, (P1(1) + P3(1)) / 2, ' D ', 'Color', [0.5 0.5 0.5], 'FontWeight', 'bold');
            
            % Linhas de Chamada (Tracejadas Verticais - Projeções)
            plot(app.AxLateral, [P1(1) P1(1)], [0 P1(2)], 'k:', 'LineWidth', 1);
            plot(app.AxLateral, [P2(1) P2(1)], [0 P2(2)], 'k:', 'LineWidth', 1);
            plot(app.AxLateral, [P3(1) P3(1)], [0 P3(2)], 'k:', 'LineWidth', 1);

            % Coordenadas (R2, Z2), etc.
            text(app.AxLateral, P1(1)-7, P1(2)+3, '(R_2, Z_2)', 'FontSize', 10);
            text(app.AxLateral, P2(1), P2(2)-3, '(R_3, Z_3)', 'FontSize', 10, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'top');
            text(app.AxLateral, P3(1)-2, P3(2)+3, '(R_4, Z_4)', 'FontSize', 10);

            % 4. Ajustes de Eixo
            axis(app.AxLateral, 'equal');
            grid(app.AxLateral, 'on');
            xlabel(app.AxLateral, 'Eixo R (Alcance Horizontal cm)');
            ylabel(app.AxLateral, 'Eixo Z (Altura cm)');
            app.AxLateral.XLim = [-5, 55]; % Margem negativa para mostrar o L1
            app.AxLateral.YLim = [-5, 45];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            atualizarDiagramasGeometricos(app);
        end

        % Button pushed function: VoltaraoMenuButton
        function VoltaraoMenuButtonPushed(app, event)
            modeloSimulink = 'Montagem2'; 
            
            % Para o Simulink se estiver rodando
            try
                % Verifica se o modelo está carregado antes de tentar parar
                if bdIsLoaded(modeloSimulink)
                    set_param(modeloSimulink, 'SimulationCommand', 'stop');
                end
            catch
                % Se der erro ou já estiver fechado, apenas segue para a
                % janela "Menu Principal"
            end

            % Abre o Menu Principal
            MenuPrincipal; 
            
            % Fecha o laboratório atual
            delete(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Título da Janela
            titulo = 'Simplificação Cinemática: O Elo Efetudor';
            
            % Texto Explicativo
            mensagem = [ ...
                'Por que somamos L4 + L5?' newline newline ...
                '1. Modelo Físico: O elo L4 conecta o punho à base da garra, e L5 é o comprimento da própria garra.' newline ...
                '2. Desacoplamento: Para o cálculo da cinemática, o objetivo é localizar a garra no espaço tridimensional (Px, Py e Pz). ' ...
                'Logo, o que importa é posição final da garra.' newline ...
                '3. Simplificação: Consideramos o conjunto (Punho + Garra) como um vetor único de alcance na vista lateral. ' ...
                'A rotação da garra (abrir/fechar ou girar sobre o próprio eixo) não afeta o cálculo geométrico da cinemática.'];
            
            % Ícone
            uialert(app.UIFigure, mensagem, titulo, 'Icon', 'info');
        end

        % Value changed function: CinematicaDropDown
        function CinematicaDropDownValueChanged(app, event)
            value = app.CinematicaDropDown.Value;
            if strcmp(value, 'Direta')
                app.LabelEqDireta.Visible = 'on';
                app.LabelEqInversa.Visible = 'off';
            elseif strcmp(value, 'Inversa')
                app.LabelEqDireta.Visible = 'off';
                app.LabelEqInversa.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VisoGeralTab = uitab(app.TabGroup);
            app.VisoGeralTab.Title = 'Visão Geral';
            app.Panel = uipanel(app.VisoGeralTab);
            app.ManipuladorDidticode5JuntasRotativasLabel = uilabel(app.Panel);
            app.ManipuladorDidticode5JuntasRotativasLabel.Text = 'Manipulador Didático de 5 Juntas Rotativas';
            app.EspecificaesLabel = uilabel(app.Panel);
            app.EspecificaesLabel.Text = 'Especificações';
            app.GrausdeLiberdadeLabel = uilabel(app.Panel);
            app.GrausdeLiberdadeLabel.Text = 'Graus de Liberdade:';
            app.GrausdeLiberdade5RotacionaisLabel_2 = uilabel(app.Panel);
            app.GrausdeLiberdade5RotacionaisLabel_2.Text = '  Este é um manipulador antropomórfico desenvolvido para fins educacionais. Ele possui 5 graus de liberdade (DOF), permitindo posicionar o efetuador final (garra) em qualquer ponto do seu espaço de trabalho tridimensional. A estrutura é acionada por servomotores controlados via microcontrolador.';
            app.RotacionaisLabel = uilabel(app.Panel);
            app.RotacionaisLabel.Text = '5 Rotacionais';
            app.AtuadoresLabel = uilabel(app.Panel);
            app.AtuadoresLabel.Text = '                Atuadores:';
            app.ServoMotorMG9952MicroServoMG90SLabel = uilabel(app.Panel);
            app.ServoMotorMG9952MicroServoMG90SLabel.Text = {'4 Servo Motor MG995                   '; '2 Micro Servo MG90S'};
            app.SensorLabel = uilabel(app.Panel);
            app.SensorLabel.Text = '                     Sensor:';
            app.CmeradeSmartphoneLabel = uilabel(app.Panel);
            app.CmeradeSmartphoneLabel.Text = 'Câmera de Smartphone';
            app.ESP32Label_2 = uilabel(app.Panel);
            app.ESP32Label_2.Text = ' ESP32';
            app.ConversorBuckePCILabel = uilabel(app.Panel);
            app.ConversorBuckePCILabel.Text = 'Conversor Buck e PCI';
            app.UnidadedeControleLabel_2 = uilabel(app.Panel);
            app.UnidadedeControleLabel_2.Text = 'Unidade de Controle:';
            app.UnidadedePotnciaLabel_2 = uilabel(app.Panel);
            app.UnidadedePotnciaLabel_2.Text = 'Unidade de Potência:';
            app.Panel_2 = uipanel(app.VisoGeralTab);
            app.Image = uiimage(app.Panel_2);
            app.Image.ImageSource = 'braco-juntas-elos.png';
            app.DecomposioGeomtricaTab = uitab(app.TabGroup);
            app.DecomposioGeomtricaTab.Title = 'Decomposição Geométrica';
            app.Panel_6 = uipanel(app.DecomposioGeomtricaTab);
            app.AxSuperior = uiaxes(app.Panel_6);
            xlabel(app.AxSuperior, 'X')
            ylabel(app.AxSuperior, 'Y')
            zlabel(app.AxSuperior, 'Z')
            app.VistaSuperiorPlanoXYLabel = uilabel(app.Panel_6);
            app.VistaSuperiorPlanoXYLabel.Text = 'Vista Superior (Plano XY)';
            app.GrausdeLiberdade5RotacionaisLabel_4 = uilabel(app.Panel_6);
            app.GrausdeLiberdade5RotacionaisLabel_4.Text = 'A rotação da base depende exclusivamente das coordenadas Px e Py. O braço gira até alinhar-se com o alvo.';
            app.DesacoplamentoCinemticoMtodoGeomtricoLabel = uilabel(app.DecomposioGeomtricaTab);
            app.DesacoplamentoCinemticoMtodoGeomtricoLabel.Text = 'Desacoplamento Cinemático - Método Geométrico';
            app.DesacoplamentoCinemticoMtodoGeomtricoLabel_2 = uilabel(app.DecomposioGeomtricaTab);
            app.DesacoplamentoCinemticoMtodoGeomtricoLabel_2.Text = 'O método geométrico simplifica o problema 3D dividindo-o em dois problemas (planos 2D) independentes.';
            app.Panel_7 = uipanel(app.DecomposioGeomtricaTab);
            app.AxLateral = uiaxes(app.Panel_7);
            xlabel(app.AxLateral, 'X')
            ylabel(app.AxLateral, 'Y')
            zlabel(app.AxLateral, 'Z')
            app.VistaLateralPlanoRZLabel = uilabel(app.Panel_7);
            app.VistaLateralPlanoRZLabel.Text = 'Vista Lateral (Plano RZ)';
            app.GrausdeLiberdade5RotacionaisLabel_5 = uilabel(app.Panel_7);
            app.GrausdeLiberdade5RotacionaisLabel_5.Text = 'Neste plano, a posição final é a soma vetorial das projeções de cada elo (L2, L3, L4+L5. A cinemática inversa utiliza a geometria deste arranjo para determinar os ângulos theta.';
            app.Button = uibutton(app.Panel_7, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.ModelagemMatemticaTab = uitab(app.TabGroup);
            app.ModelagemMatemticaTab.Title = 'Modelagem Matemática';
            app.Panel_8 = uipanel(app.ModelagemMatemticaTab);
            app.LabelEqInversa = uilabel(app.Panel_8);
            app.LabelEqInversa.Visible = 'off';
            app.LabelEqInversa.Interpreter = 'latex';
            app.LabelEqInversa.Text = {'$R = \sqrt{P_x^2 + P_y^2}$'; '$Z = P_z - L_1$'; '$\theta_1 = atan2(P_y, P_x)$'; '$R_3 = R - L_4 \cdot \cos(\phi)$'; '$Z_3 = Z - L_4 \cdot \sin(\phi)$'; '$D^2 = R_3^2 + Z_3^2$'; '$\cos(\theta_3) = \frac{D^2 - L_2^2 - L_3^2}{2 \cdot L_2 \cdot L_3}$'; '$A = L_2 + L_3 \cdot \cos(\theta_3)'; '$B = L_3 \cdot \sin(\theta_3)$'; '$\theta_2$ = atan2( \frac{$Z_3$ \cdot A + $R_3$ \cdot B}{$D^2$} , \frac{$R_3$ \cdot A - $Z_3$ \cdot B}{$D^2$})'; '$\theta_4 = \phi - \theta_2 + \theta_3$'};
            app.CinemticaDropDownLabel = uilabel(app.Panel_8);
            app.CinemticaDropDownLabel.Text = 'Cinemática';
            app.CinematicaDropDown = uidropdown(app.Panel_8);
            app.CinematicaDropDown.Items = {'Direta', 'Inversa'};
            app.CinematicaDropDown.ValueChangedFcn = createCallbackFcn(app, @CinematicaDropDownValueChanged, true);
            app.CinematicaDropDown.Value = 'Direta';
            app.LabelEqDireta = uilabel(app.Panel_8);
            app.LabelEqDireta.Interpreter = 'latex';
            app.LabelEqDireta.Text = {'$\phi = \theta_2 - \theta_3 + \theta_4$ (Pitch Geral)'; '$R = L_2 \cdot cos(\theta_2) + L_3 \cdot cos(\theta_2 - \theta_3) + L_4 \cdot cos(\phi)$'; '$Z = L_2 \cdot sin(\theta_2) + L_3 \cdot sin(\theta_2 - \theta_3) + L_4 \cdot sin(\phi)$'; '$P_x = R \cdot cos(\theta1)$'; '$P_y = R \cdot sin(\theta1)$'; '$P_z = Z + L_1 (offset)$'};
            app.Tabela1ElosdoManipuladorLabel = uilabel(app.ModelagemMatemticaTab);
            app.Tabela1ElosdoManipuladorLabel.Text = 'Tabela 1 - Elos do Manipulador';
            app.Image2 = uiimage(app.ModelagemMatemticaTab);
            app.Image2.ImageSource = 'Info_Elos.svg';
            app.Tabela2VariveisdeJuntaLabel = uilabel(app.ModelagemMatemticaTab);
            app.Tabela2VariveisdeJuntaLabel.Text = 'Tabela 2 - Variáveis de Junta';
            app.Image2_2 = uiimage(app.ModelagemMatemticaTab);
            app.Image2_2.ImageSource = 'Info_Thetas.svg';
            app.VoltaraoMenuButton = uibutton(app.UIFigure, 'push');
            app.VoltaraoMenuButton.ButtonPushedFcn = createCallbackFcn(app, @VoltaraoMenuButtonPushed, true);
            app.VoltaraoMenuButton.Text = 'Voltar ao Menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SalaDeAula

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end